classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        DirectoryTextAreaLabel         matlab.ui.control.Label
        DirectoryTextArea              matlab.ui.control.TextArea
        ExportButton                   matlab.ui.control.Button
        ImplementationYearDropDownLabel  matlab.ui.control.Label
        ImplementationYearDropDown     matlab.ui.control.DropDown
        SelectButton_2                 matlab.ui.control.Button
        TotalCostTextAreaLabel         matlab.ui.control.Label
        TotalCostTextArea              matlab.ui.control.TextArea
        TotalWedgesTextAreaLabel       matlab.ui.control.Label
        TotalWedgesTextArea            matlab.ui.control.TextArea
        TextArea_10                    matlab.ui.control.TextArea
        StrategiesFileLabel            matlab.ui.control.Label
        WedgesFileLabel                matlab.ui.control.Label
        ConstraintsFileLabel           matlab.ui.control.Label
        UploadButton_2                 matlab.ui.control.Button
        UploadButton_3                 matlab.ui.control.Button
        UploadButton_4                 matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        UploadedWedgeChoicesDropDownLabel  matlab.ui.control.Label
        UploadedWedgeChoicesDropDown   matlab.ui.control.DropDown
        SelectButton                   matlab.ui.control.Button
        SectorContraintsLabel          matlab.ui.control.Label
        CountersLabel                  matlab.ui.control.Label
        TextArea_7                     matlab.ui.control.TextArea
        TextArea                       matlab.ui.control.TextArea
        TextArea_6                     matlab.ui.control.TextArea
        TextArea_2                     matlab.ui.control.TextArea
        TextArea_5                     matlab.ui.control.TextArea
        TextArea_8                     matlab.ui.control.TextArea
        TextArea_3                     matlab.ui.control.TextArea
        TextArea_4                     matlab.ui.control.TextArea
        TextArea_9                     matlab.ui.control.TextArea
        DialogueBoxTextAreaLabel       matlab.ui.control.Label
        DialogueBoxTextArea            matlab.ui.control.TextArea
        RemoveButton                   matlab.ui.control.Button
        SelectedWedgeDescriptionLabel  matlab.ui.control.Label
        SelectedWedgeDescriptionTextArea  matlab.ui.control.TextArea
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        FlatlineButton                 matlab.ui.control.ToggleButton
        LevelsButton                   matlab.ui.control.ToggleButton
        LevelsButton_2                 matlab.ui.control.ToggleButton
        WedgesNeededToLabel            matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        Button_6                       matlab.ui.control.Button
        Button_7                       matlab.ui.control.Button
        Button_8                       matlab.ui.control.Button
        NuclearLabel                   matlab.ui.control.Label
        FuelLabel                      matlab.ui.control.Label
        ConservationLabel              matlab.ui.control.Label
        BiostorageLabel                matlab.ui.control.Label
        UnmitigatedEmissionsLabel      matlab.ui.control.Label
        KeyLabel                       matlab.ui.control.Label
        RandomButton                   matlab.ui.control.Button
        AtmosphericCarbonppmvsTimeTab  matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        MeanWarmingDegreesCelsiusvsTimeTab  matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        EnterUserValueForLambdaandPressSelectTextAreaLabel  matlab.ui.control.Label
        EnterUserValueForLambdaandPressSelectTextArea  matlab.ui.control.TextArea
        SelectButton_3                 matlab.ui.control.Button
    end


    properties (Access = private)
        E0 % The total emissions at year 2017
        E1 % total business as usual emission at 2068
        time % original array from 1959-2017
        emissions % historical emissions as described in .txt file
        historicalData % directory of historicaldata.txt
        BaU %anonymous function for business as usual graph
        t %time array from 2017 -> 2068
        wedgefile %the split cell from the wedge file
        wedgesbeingused %a dynamic list of selected strategies
        wedgeheader %the headerline to the wedge file.
        output %cell array to be printed onto output.txt
        wedgedir % directory of the wedges.txt file
        start_year %user defined beginning of wedge
        t_imp % an array of time from implementation year to 2068
        flatline_E %an array the size of t_imp which contains the E flat value
        E90 % Emission levels at 1990
        E98 %80 percent of emission levels of 1990
        goaldesc % char array of goal selection '1990', 'flat', or '80%'
        ppm % Description of atm carbon in ppm from 1959-2068
        lambda % lambda for the mean warming versus 
        delta_F %an array which details the change in radiative force over each year
        warming % an anonymous function for the warming based on delta_F
    end

    methods (Access = private)
% myGetVar from lab 6
function [var, header] = myGetVar(app,file_name, index)
%generates the header line%
fclose('all');
fileID = fopen(file_name);
file_line = fgetl(fileID);
i = 1;
while ischar(file_line)
    doc_cell{i,1} = file_line;
    i = i + 1;
    file_line = fgetl(fileID);
end
fclose('all');
[m,~] = size(doc_cell);
head = doc_cell{1,1};
header = [];
j = 2;
T = str2num(head);
while isempty(T)
    header = [header head];
    head = doc_cell{j,1};
    T = str2num(head);
    j = j + 1;
end
k = j - 1;
v = 1;
var_cell = cell(m-k+1,1);
for l = k:m
    var_cell{v,1} = doc_cell{l,1};
    v = v + 1;
end
[n,~] = size(var_cell);
var_mat = [];
for y = 1:n
    var_mat = [var_mat; str2num(var_cell{y,1})];
end
var = [var_mat(:,index)]';

end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
hold(app.UIAxes, 'on');
app.TotalCostTextArea.Value = '0';
app.wedgesbeingused = {};
app.TotalWedgesTextArea.Value = '0';
app.TextArea_10.Value = '0';
app.time = 1959:1:2017;
app.emissions = [2.45, 2.57, 2.58, 2.69, 2.83, 3.00, 3.13, 3.29, 3.39, 3.57, 3.78, 4.05,...
    4.21, 4.38, 4.61, 4.62, 4.60, 4.86, 5.02, 5.07, 5.36, 5.30, 5.14, 5.09, 5.08,...
    5.26, 5.42, 5.58, 5.73, 5.94, 6.07, 6.06, 6.13, 6.07, 6.06, 6.17, 6.30, 6.44, 6.55, 6.57, 6.55,...
    6.72, 6.89, 6.99, 7.37, 7.73, 8.03, 8.32, 8.49, 8.77, 8.69, 9.12, 9.50, 9.67,...
    9.77, 9.85, 9.83, 9.88, 10.0];
app.E0 = 10.0;
app.E90 = 6.06;
app.E98 = 0.8*6.06;
app.BaU = @(t) app.E0.*2.^((t-2017)/50);
app.t = 2017:1:2068;
app.E1 = app.BaU(2068);
plot(app.UIAxes,app.time, app.emissions, 'k-', app.t, app.BaU(app.t), 'r-');
app.UIAxes.XLim = [1959 2068];
legend(app.UIAxes,'Historic Emissions', 'Business As Usual', 'Location', 'northwest');
app.t_imp = [];
app.TextArea_7.Value = '0';
app.TextArea_8.Value = '0';
app.TextArea_9.Value = '0';

app.ppm = zeros(1,numel(1959:1:2068));
app.ppm(42) = 800;
for i = 41:-1:1
    app.ppm(i) = app.ppm(i+1) - 0.5*(app.BaU(i+1958));
end
for i = 43:110
    app.ppm(i) = app.ppm(i-1) + 0.5*(app.BaU(i+1958));
end
app.ppm = app.ppm/2.1;
app.lambda = 0.7;
app.delta_F = 5.35.*log(app.ppm./278);
app.warming = @(lambda) lambda*app.delta_F;
hold(app.UIAxes2, 'on');
plot(app.UIAxes2, 1959:2068, app.ppm, 'k-')
legend(app.UIAxes2, 'Baseline');
app.UIAxes2.XLim = [1959 2068];
app.UIAxes2.YLim = [min(app.ppm) max(app.ppm)];
hold(app.UIAxes3, 'on')
plot(app.UIAxes3, 1959:2068, app.warming(app.lambda), 'k-');
legend(app.UIAxes3, 'Baseline')
app.UIAxes3.XLim = [1959 2068];
app.UIAxes3.YLim = [min(app.warming(app.lambda)) max(app.warming(app.lambda))];
        end

        % Callback function
        function SelectButtonPushed(app, event)

        end

        % Callback function
        function SelectButton_2Pushed(app, event)

        end

        % Callback function
        function SelectButton_3Pushed(app, event)

        end

        % Button pushed function: UploadButton_4
        function UploadButton_4Pushed(app, event)
try
%this code block will allow preselected strategies to be uploaded into the app. 
app.DialogueBoxTextArea.Value = '';
try
    %this first block will ensure that the app does not have to be reset before uploading strat
    if isempty(app.t_imp)
        [filename, dir] = uigetfile('*.txt');
        fID = fopen(strcat(dir,filename));
        if fID == -1
            app.DialogueBoxTextArea.Value = 'Could not import strategies.txt file';
        else
            header = fgetl(fID);
            if strcmp(header, 'ID YearStart')
                stratsplit = [];
                while ~feof(fID)
                stratsplit = [stratsplit; strsplit(fgetl(fID))];
                end
                app.start_year = str2double(stratsplit{1,2});
                app.t_imp = app.start_year:2068;
                app.E1 = app.BaU(app.start_year+50);
                app.flatline_E = app.BaU(app.start_year).*ones(size(app.t_imp));
                app.TextArea_10.Value = num2str(round(app.BaU(app.start_year)));
                plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'DisplayName', 'Flatline Goal');
                [m,~] = size(stratsplit);
                ID_array = cell(m,1);
                for i = 1:m
                    ID_array{i} = stratsplit{i,1};
                end
                [J,~] = size(app.wedgefile);
                index = [];
                for i = 1:m
                    for j = 1:J
                        if strcmp(ID_array{i}, app.wedgefile{j,1})
                            index = [index, j];
                        end
                    end
                end
            %this code block will take the indices of the wedges in the wedge file and graph each one as described
            %in the strategy file
                for i = index
                app.E1 = app.E1 - 1;
                y = log([app.BaU(app.start_year), app.E1])';
                y_hat = log([app.BaU(app.start_year), (app.E1+1)])';
                A = [1, app.start_year; 1, (app.start_year+50)];
                app.TextArea_10.Value = num2str(round(app.BaU(app.start_year))); 
                coefs = A\y;
                coefs2 = A\y_hat;
                coefs = exp(coefs);
                coefs2 = exp(coefs2);
                if strcmp(app.wedgefile{i,2},'Efficiency and Conservation')
                    area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','y','basevalue',app.BaU(app.start_year),'showbaseline','off', 'Edgecolor','k', 'DisplayName', app.wedgefile{i,3});
                    area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','w','Edgecolor','k','basevalue',app.BaU(app.start_year),'showbaseline','off','HandleVisibility', 'off');
                elseif strcmp(app.wedgefile{i,2}, 'Fossil Fuel')
                    area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','b','basevalue',app.BaU(app.start_year),'showbaseline','off', 'Edgecolor','k', 'DisplayName', app.wedgefile{i,3});
                    area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','w','Edgecolor','k','basevalue',app.BaU(app.start_year),'showbaseline','off','HandleVisibility', 'off');
                elseif strcmp(app.wedgefile{i,2}, 'Renewables and Biostorage')
                    area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','g','basevalue',app.BaU(app.start_year),'showbaseline','off', 'Edgecolor','k', 'DisplayName', app.wedgefile{i,3});
                    area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','w','Edgecolor','k','basevalue',app.BaU(app.start_year),'showbaseline','off','HandleVisibility', 'off');
                elseif strcmp(app.wedgefile{i,2}, 'Nuclear')
                    area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','r','basevalue',app.BaU(app.start_year),'showbaseline','off', 'Edgecolor','k', 'DisplayName', app.wedgefile{i,3});
                    area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','w','Edgecolor','k','basevalue',app.BaU(app.start_year),'showbaseline','off','HandleVisibility', 'off');
                end
                end
            else
                app.DialogueBoxTextArea.Value = 'Please ensure you have uploaded STRATEGIES file.';
                return
            end
            %this block of code will update anything logistical that has to do with the strat file
            app.TotalWedgesTextArea.Value = num2str(m);
            plot(app.UIAxes, app.t_imp, app.flatline_E, 'w--', 'HandleVisibility', 'off');
            plot(app.UIAxes, app.t, app.BaU(app.t), 'r-', 'HandleVisibility', 'off');
            total = [];
            for i = 1:numel(index)
                cost = str2double(app.wedgefile{index(i), 8});
                total = [total, cost];
            end
            total = sum(total);
            app.TotalCostTextArea.Value = num2str(total);
            for i = index
            app.wedgesbeingused = [app.wedgesbeingused; app.wedgefile{i, 3}];
            end
    
        end
        sectorsplit = [];
        for i = index
            if size(strsplit(app.wedgefile{i,4},',')) == [1 2]
                sectorsplit = [sectorsplit;strsplit(app.wedgefile{i,4},',')];
            else
                sectorsplit = [sectorsplit; {app.wedgefile{i,4}, 'Null'}];
            end
        end
        %this block of code will update contraints count for selected strategy
        for i = 1:numel(index)
            for s = 1:2
                if strcmp(app.TextArea.Value{:}, sectorsplit{i,s})
                    if strcmp(app.TextArea_6.Value{:,:},app.TextArea_7.Value{:,:})
                        app.DialogueBoxTextArea.Value = 'Please select a strategy from a different sector';
                        return
                    else
                        app.TextArea_7.Value = num2str(str2double(app.TextArea_7.Value{:,:}) + 1);
                    end
                    if strcmp(app.TextArea_5.Value{:,:},app.TextArea_8.Value{:,:})
                        app.DialogueBoxTextArea.Value = 'Please select a strategy from a different sector';
                        return
                    else
                        app.TextArea_8.Value = num2str(str2double(app.TextArea_8.Value{:,:}) + 1);
                    end
                    if strcmp(app.TextArea_4.Value{:,:},app.TextArea_9.Value{:,:})
                        app.DialogueBoxTextArea.Value = 'Please select a strategy from a different sector';
                        return
                    else
                        app.TextArea_9.Value = num2str(str2double(app.TextArea_9.Value{:,:}) + 1);
                    end
                end
            end
        end
    else
        app.DialogueBoxTextArea.Value = 'Please reset implementation year before attempting to select a strategy file';
    end
    [m, ~] = size(app.wedgesbeingused);
    
    app.E1 = app.BaU(2068);
    l = app.start_year - 1958;
    for i = 1:m
        app.E1 = app.E1 - 1;
    end
        y = log([app.BaU(app.start_year), app.E1])';
        y_hat = log([app.BaU(app.start_year), (app.E1+1)])';
        A = [1, app.start_year; 1, (app.start_year+50)];
        coefs = A\y;
        coefs = exp(coefs);
        ppm_new(1) = 2.1*app.ppm(l);
        for i = 1:numel(l:109)
            ppm_new(i+1) = ppm_new(i) + 0.5*(coefs(1)*coefs(2).^(app.t_imp(i)));
        end
        plot(app.UIAxes2, app.t_imp, ppm_new, 'r--', 'DisplayName', 'Current Strategy');
        delta_F = 5.35*log(ppm_new./278);
        warming = @(lambda) lambda*delta_F;
        plot(app.UIAxes3, app.t_imp, warming(app.lambda), 'r--', 'DisplayName', 'Current Strategy');


catch
    app.DialogueBoxTextArea.Value = 'Please ensure you have uploaded a wedges.txt file before attempting to upload a premade strategies file.';
end
catch
    app.DialogueBoxTextArea.Value = 'Something went wrong. Please refer to the tutorial.';
end
        end

        % Callback function
        function UploadButtonPushed(app, event)

        end

        % Button pushed function: UploadButton_3
        function UploadButton_3Pushed(app, event)
    %This button will allow the user to upload a file detailing wedge options. The options will appear
    % in the dropdown box
try
    app.DialogueBoxTextArea.Value = '';        
          
   [filename, dir] = uigetfile('*.txt');
          categories = [];
          app.wedgedir = strcat(dir, filename);
          fID = fopen(strcat(dir, filename));
          if fID ~=-1
            try
              app.wedgeheader = fgetl(fID);
            while ~feof(fID)
                line = fgetl(fID);
                categories = [categories; strsplit(line, ';')];
            end
            app.wedgefile = [categories];
            app.UploadedWedgeChoicesDropDown.Items = {categories{:, 3}};
            fclose('all');
            catch
                app.DialogueBoxTextArea.Value = 'Please input the WEDGES file from this upload bar.';
            end
          else
              app.DialogueBoxTextArea.Value = 'Error: Could not sucessfully load in wedges input file.';
          end
catch
    app.DialogueBoxTextArea.Value = 'Something went wrong. Refer to the tutorial.';
end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed2(app, event)
% the select button will activate the choice of wedge and update the graph
try
app.SelectedWedgeDescriptionTextArea.Value = '';
if isempty(app.t_imp)
    app.DialogueBoxTextArea.Value = 'Please select an implementation year before selecting strategies';
else
currentWedge = app.UploadedWedgeChoicesDropDown.Value;
app.DialogueBoxTextArea.Value = '';
[m,~] = size(app.wedgefile);
app.wedgesbeingused = [app.wedgesbeingused; {currentWedge}];
[k,~] = size(app.wedgesbeingused);
j = 0;
% the char current wedge is stored in app.wedgesbeingused to be referenced by the export button

%this code will find additional info about the wedge in the original wedge file
%this code chunk will ensure we dont go over contraints
if k == 1
    [];
else
    for i = 1:k
        if strcmp(app.wedgesbeingused{i,:}, currentWedge)
            j = j+1;
            if rem(k,2) == 1
                if j >= k/2
                    app.wedgesbeingused(k,:) = [];
                    app.DialogueBoxTextArea.Value = 'Error: a single strategy cannot account for more than half of the wedges used.';
                    return
                else
                app.DialogueBoxTextArea.Value = '';
                end
            elseif rem(k,2) == 0
                if j > k/2
                    app.wedgesbeingused(k,:) = [];
                    app.DialogueBoxTextArea.Value = 'Error: a single strategy cannot account for more than half of the wedges used.';
                    return
                else
                app.DialogueBoxTextArea.Value = '';
                end
            end
        end
    end
end
%logistical info
app.TotalWedgesTextArea.Value = num2str(k);
%accounting for dynamically updating contraints counts
sectorsplit = [];
for i = 1:m
    if size(strsplit(app.wedgefile{i,4},',')) == [1 2]
        sectorsplit = [sectorsplit;strsplit(app.wedgefile{i,4},',')];
    else
        sectorsplit = [sectorsplit; {app.wedgefile{i,4}, 'Null'}];
    end
end
for i = 1:m
    for s = 1:2
        if strcmp(currentWedge, app.wedgefile{i,3})
            if strcmp(app.TextArea.Value{:}, sectorsplit{i,s})
                if strcmp(app.TextArea_6.Value{:,:},app.TextArea_7.Value{:,:})
                    app.DialogueBoxTextArea.Value = 'Please select a strategy from a different sector';
                    return
                else
                    app.TextArea_7.Value = num2str(str2double(app.TextArea_7.Value{:,:}) + 1);
                end
            elseif strcmp(app.TextArea_2.Value{:}, sectorsplit{i,s})
                if strcmp(app.TextArea_5.Value{:,:},app.TextArea_8.Value{:,:})
                    app.DialogueBoxTextArea.Value = 'Please select a strategy from a different sector';
                    return
                else
                    app.TextArea_8.Value = num2str(str2double(app.TextArea_8.Value{:,:}) + 1);
                end
            elseif strcmp(app.TextArea_3.Value{:}, sectorsplit{i,s})
                if strcmp(app.TextArea_4.Value{:,:},app.TextArea_9.Value{:,:})
                    app.DialogueBoxTextArea.Value = 'Please select a strategy from a different sector';
                    return
                else
                    app.TextArea_9.Value = num2str(str2double(app.TextArea_9.Value{:,:}) + 1);
                end
            end
        end
    end
end
%logistics - cost
app.E1 = app.E1 - 1;     
for i = 1:m
    if strcmp(currentWedge, app.wedgefile{i,3})
        app.TotalCostTextArea.Value = num2str(str2double(app.TotalCostTextArea.Value{:,:})...
            + str2double(app.wedgefile{i,8}));
    end
end


[J,~] = size(app.wedgefile);
    for j = 1:J
        if strcmp(currentWedge, app.wedgefile{j,3})
           index = [j];
        end
    end
%the fun stuff - color coded graphing and exponential interpolation to properly fit each wedge.
app.SelectedWedgeDescriptionTextArea.Value = strcat('Description: ',app.wedgefile{index,5}, '; Needs: ', app.wedgefile{index, 6}, '; Challenge: ', app.wedgefile{index, 7});
y = log([app.BaU(app.start_year), app.E1])';
y_hat = log([app.BaU(app.start_year), (app.E1+1)])';
A = [1, app.start_year; 1, (app.start_year+50)];
coefs = A\y;
coefs2 = A\y_hat;
coefs = exp(coefs);
coefs2 = exp(coefs2);
if strcmp(app.wedgefile{index,2},'Efficiency and Conservation')
        area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','y','basevalue',app.E98,'showbaseline','off', 'Edgecolor','k', 'DisplayName', currentWedge);
        area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','c','Edgecolor','k','basevalue',app.E98,'showbaseline','off','HandleVisibility', 'off');
        plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'HandleVisibility', 'off');
elseif strcmp(app.wedgefile{index,2}, 'Fossil Fuel')
        area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','b','basevalue',app.E98,'showbaseline','off', 'Edgecolor','k', 'DisplayName', currentWedge);
        area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','c','Edgecolor','k','basevalue',app.E98,'showbaseline','off','HandleVisibility', 'off');
        plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'HandleVisibility', 'off');
elseif strcmp(app.wedgefile{index,2}, 'Renewables and Biostorage')
        area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','g','basevalue',app.E98,'showbaseline','off', 'Edgecolor','k', 'DisplayName', currentWedge);
        area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','c','Edgecolor','k','basevalue',app.E98,'showbaseline','off','HandleVisibility', 'off');
        plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'HandleVisibility', 'off');
elseif strcmp(app.wedgefile{index,2}, 'Nuclear')
        area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','r','basevalue',app.E98,'showbaseline','off', 'Edgecolor','k', 'DisplayName', currentWedge);
        area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','c','Edgecolor','k','basevalue',app.E98,'showbaseline','off','HandleVisibility', 'off');
        plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'HandleVisibility', 'off');
end
plot(app.UIAxes, app.t, app.BaU(app.t), 'r-', 'HandleVisibility', 'off');
    
cla(app.UIAxes2, 'reset');
hold(app.UIAxes2, 'on');
plot(app.UIAxes2, 1959:2068, app.ppm, 'k-');
legend(app.UIAxes2, 'Baseline', 'Location', 'northwest');
xlabel(app.UIAxes2, 'Time (yrs)');
ylabel(app.UIAxes2, 'Atm Carbon (ppm)');
l = app.start_year-1958;
ppm_new = zeros(1,numel(l:110));
ppm_new(1) = 2.1*app.ppm(l);
for i = 1:numel(l:109)
    ppm_new(i+1) = ppm_new(i) + 0.5*(coefs(1).*coefs(2).^(app.t_imp(i)));
end
ppm_new = ppm_new/2.1;
plot(app.UIAxes2, app.t_imp, ppm_new, 'r--', 'DisplayName', 'Current Strategy');
cla(app.UIAxes3, 'reset');
hold(app.UIAxes3, 'on');
plot(app.UIAxes3, 1959:2068, app.warming(app.lambda), 'k-');
legend(app.UIAxes3, 'Baseline', 'Location', 'northwest');
xlabel(app.UIAxes3, 'Time (yrs)');
ylabel(app.UIAxes3, 'Mean Warming (C)');
app.delta_F = 5.35*log(ppm_new./278);
warming_ = @(lambda) lambda*app.delta_F;
plot(app.UIAxes3, app.t_imp, warming_(app.lambda), 'r--', 'DisplayName', 'Current Strategy');
app.delta_F = 5.35*log(app.ppm./278);
end
catch
    app.DialogueBoxTextArea.Value = 'Something went wrong. Please ensure you are properly following tutorial directions.';
end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           %Will clear the app and bring the graph back to business as usual.
 try 
           app.SelectedWedgeDescriptionTextArea.Value = ''; 
            app.wedgefile = [];
            app.UploadedWedgeChoicesDropDown.Items = {};
            app.TextArea.Value = '';
            app.TextArea_2.Value = '';
            app.TextArea_3.Value = '';
            app.TextArea_4.Value = '';
            app.TextArea_5.Value = '';
            app.TextArea_6.Value = '';
            app.TotalCostTextArea.Value = '0';
            app.wedgesbeingused = {};
            app.TotalWedgesTextArea.Value = '0';
            app.TextArea_10.Value = '0';
            app.DialogueBoxTextArea.Value = ''; 
            clear('app.t_imp', 'app.start_year');
            cla(app.UIAxes, 'reset')
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,app.time, app.emissions, 'k-', app.t, app.BaU(app.t), 'r-');
            app.UIAxes.XLim = [1959 2068];
            legend(app.UIAxes,'Historic Emissions', 'Business As Usual', 'Location', 'northwest');
            xlabel(app.UIAxes, 'Time (years)')
            ylabel(app.UIAxes, 'Carbon Emissions (GtC/yr)')
            cla(app.UIAxes2, 'reset');
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, 1959:2068, app.ppm, 'k-');
            legend(app.UIAxes2, 'Baseline');
            xlabel(app.UIAxes2, 'Time (yrs)');
            ylabel(app.UIAxes2, 'Atm Carbon (ppm)');
            cla(app.UIAxes3, 'reset');
            hold(app.UIAxes3, 'on');
            app.lambda = 0.7;
            plot(app.UIAxes3, 1959:2068, app.warming(0.7), 'k-');
            legend(app.UIAxes3, 'Baseline');
            xlabel(app.UIAxes3, 'Time (yrs)');
            ylabel(app.UIAxes3, 'Mean Warming (C)');
            app.t_imp = [];
            app.TextArea_7.Value = '0';
            app.TextArea_8.Value = '0';
            app.TextArea_9.Value = '0';
            app.TotalWedgesTextArea.Value = '0';
 catch
     app.DialogueBoxTextArea.Value = 'Something Went Wrong. Please refer to the tutorial.';
 end
        end

        % Button pushed function: UploadButton_2
        function UploadButton_2Pushed(app, event)
    
    %this button will take the information from the sector constraints file and display them in
    %the appropriate text boxes.
try    
    app.DialogueBoxTextArea.Value = '';
    [file, folder] = uigetfile('*.txt');
    fID = fopen(strcat(folder,file));
    if fID ~=-1
        try
        line = fgetl(fID);
        if strcmp(line, 'Sector Constraints')
        sectors = [];
        while ~feof(fID)
            line = fgetl(fID);
            sectors = [sectors; strsplit(line)];
        end
app.TextArea.Value = sectors{1,1};
app.TextArea_2.Value = sectors{2,1};
app.TextArea_3.Value = sectors{3,1};
app.TextArea_4.Value = sectors{3,2};
app.TextArea_5.Value = sectors{2,2};
app.TextArea_6.Value = sectors{1,2};
        else
            app.DialogueBoxTextArea.Value = 'Please input the CONSTRAINTS file through this upload bar.';
        end
        catch
            app.DialogueBoxTextArea.Value = 'Please input the CONSTRAINTS file through this upload bar.';
        end
    else
        app.DialogueBoxTextArea.Value = 'Error: Was not able to select file';
    end
catch
    app.DialogueBoxTextArea.Value = 'Something Went Wrong. Please refer to the tutorial.';
end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
       %this button will return the output file to the selected address     
            try
            app.DialogueBoxTextArea.Value = '';
            app.output = {};
            [K,~] = size(app.wedgesbeingused);
            [J,~] = size(app.wedgefile);
            index = [];
            for i = 1:K
                for j = 1:J
                    if strcmp(app.wedgesbeingused{i,:}, app.wedgefile{j,3})
                        index = [index, j];
                    end
                end
            end
            fileID = fopen(app.wedgedir);
            lin = strsplit(fgetl(fileID),';');
            while ~feof(fileID)
                lin = [lin; strsplit(fgetl(fileID),';')];
            end
            line = {};
            app.output{1} = 'ID;StrategyName;Sector;NumberUsed;TotalCost;Challenges';
            for i = index
                line = [line; strcat(lin{i+1,1},';',lin{i+1,3},';',lin{i+1,4},';', num2str(numel(find(index==i))), ';', num2str(numel(find(index==i))*str2double(lin{i+1,8})), ';', lin{i+1,7})];
            end
            app.output = [app.output; line];
            app.output = unique(app.output);
            app.output = flipud(app.output);  
            fID = fopen(strcat(app.DirectoryTextArea.Value{:}, 'output.txt'), 'w');
            fprintf(fID, '%s\n', app.output{:,:});
            fclose(fID);
            catch
                app.DialogueBoxTextArea.Value = 'Please select mitigation strategies before attempting to export an output file.';
            end
            
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed2(app, event)
%the select button for the implementation year will check to see if a year was not already selected
%before graphing the flatline goal and calculating the amount of wedges needed to achieve that goal 
%by 2068
try
app.DialogueBoxTextArea.Value = '';
            if isempty(app.t_imp)
            cla(app.UIAxes, 'reset');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,app.time, app.emissions, 'k-', app.t, app.BaU(app.t), 'r-');
            app.UIAxes.XLim = [1959 2068];
            xlabel(app.UIAxes, 'Time (years)');
            ylabel(app.UIAxes, 'Carbon Emissions (GtC/yr)');
            legend(app.UIAxes,'Historic Emissions', 'Business As Usual', 'Location', 'northwest');
            app.start_year = str2num(app.ImplementationYearDropDown.Value);
            app.E1 = app.BaU(app.start_year+50);
            app.t_imp = app.start_year:1:2068;
            app.flatline_E = app.BaU(app.start_year).*ones(size(app.t_imp));
            app.goaldesc = '80% 1990 Levels';
            plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'DisplayName', 'Flatline Goal');
            else
                app.DialogueBoxTextArea.Value = 'Cannot recalibrate implementation Year. To restart, select the reset button.';
            end
catch
    app.DialogueBoxTextArea.Value = 'Something went wrong. Please refer to the tutorial.';
end
            
        end

        % Callback function
        function DisplayaGraphofCO2ppmvsTimeButtonPushed(app, event)

        end

        % Button pushed function: SelectButton_3
        function SelectButton_3Pushed2(app, event)
         %this select button will employ the user defined lambda for the graph   
    try            
            app.lambda = str2double(app.EnterUserValueForLambdaandPressSelectTextArea.Value{:});
            cla(app.UIAxes3, 'reset');
            hold(app.UIAxes3, 'on');
            plot(app.UIAxes3, 1959:2068, app.warming(app.lambda));
            app.UIAxes3.XLim = [1959 2068];
            app.UIAxes3.YLim = [min(app.warming(app.lambda)) max(app.warming(app.lambda))];
            xlabel(app.UIAxes3, 'Time (yrs)')
            ylabel(app.UIAxes3, 'Mean Warming (C)')
    catch
    app.DialogueBoxTextArea.Value = 'Something went wrong. Please refer to the tutorial.';
    end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
    %this button will allow the user to remove a selected wedge, granted it is currently active
    % a majority of this code block is very similar to that of the primary select button which
    % adds wedges. That is because the approach is this code block erased the whole graph and regraphed
    % all other wedges in app.wedgesbeingused after deleting the current selected wedge
    try 
    app.DialogueBoxTextArea.Value = '';
     app.SelectedWedgeDescriptionTextArea.Value = '';
        [m,~] = size(app.wedgesbeingused);
        i = 0;       
        for j = 1:m
        if ~strcmp(app.UploadedWedgeChoicesDropDown.Value, app.wedgesbeingused{j})
            i = i + 1;
        end
        end
        if i == m
            app.DialogueBoxTextArea.Value = 'Please use remove for only active wedges.';
            return
        end
        app.TotalCostTextArea.Value = '0';
        app.TotalWedgesTextArea.Value = '0';
        app.TextArea_7.Value = '0';
        app.TextArea_8.Value = '0';
        app.TextArea_9.Value = '0';
        for i = 1:m
            if strcmp(app.UploadedWedgeChoicesDropDown.Value, app.wedgesbeingused{i})
                app.wedgesbeingused(i) = [];
                break
            end
        end
        m = m - 1;
        app.E1 = app.BaU(app.start_year+50);
        [J,~] = size(app.wedgefile);
        index = [];
        
        cla(app.UIAxes, 'reset')
        hold(app.UIAxes, 'on');
        plot(app.UIAxes,app.time, app.emissions, 'k-', app.t, app.BaU(app.t), 'r-', app.t_imp, app.BaU(app.start_year).*ones(size(app.t_imp)), 'k--');
        app.UIAxes.XLim = [1959 2068];
        legend(app.UIAxes,'Historic Emissions', 'Business As Usual', 'Flatline Goal', 'Location', 'northwest');
        xlabel(app.UIAxes, 'Time (years)')
        ylabel(app.UIAxes, 'Carbon Emissions (GtC/yr)')    
     if m~=0    
         for i = 1:m
             for j = 1:J
                 if strcmp(app.wedgesbeingused{i}, app.wedgefile{j,3})
                        index = [index, j];
                 end
             end
         end


        for i = index
            app.E1 = app.E1 - 1;
            y = log([app.BaU(app.start_year), app.E1])';
            y_hat = log([app.BaU(app.start_year), (app.E1+1)])';
            A = [1, app.start_year; 1, (app.start_year+50)];
            app.TextArea_10.Value = num2str(round(app.BaU(app.start_year))); 
            coefs = A\y;
            coefs2 = A\y_hat;
            coefs = exp(coefs);
            coefs2 = exp(coefs2);
            if strcmp(app.wedgefile{i,2},'Efficiency and Conservation')
                    area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','y','basevalue',app.E98,'showbaseline','off', 'Edgecolor','k', 'DisplayName', app.wedgefile{i,3});
                    area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','w','Edgecolor','k','basevalue',app.E98,'showbaseline','off','HandleVisibility', 'off');
                    plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'HandleVisibility', 'off');
            elseif strcmp(app.wedgefile{i,2}, 'Fossil Fuel')
                    area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','b','basevalue',app.E98,'showbaseline','off', 'Edgecolor','k', 'DisplayName', app.wedgefile{i,3});
                    area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','w','Edgecolor','k','basevalue',app.E98,'showbaseline','off','HandleVisibility', 'off');
                    plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'HandleVisibility', 'off');
            elseif strcmp(app.wedgefile{i,2}, 'Renewables and Biostorage')
                    area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','g','basevalue',app.E98,'showbaseline','off', 'Edgecolor','k', 'DisplayName', app.wedgefile{i,3});
                    area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','w','Edgecolor','k','basevalue',app.E98,'showbaseline','off','HandleVisibility', 'off');
                    plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'HandleVisibility', 'off');          
            elseif strcmp(app.wedgefile{i,2}, 'Nuclear')
                    area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','r','basevalue',app.E98,'showbaseline','off', 'Edgecolor','k', 'DisplayName', app.wedgefile{i,3});
                    area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','w','Edgecolor','k','basevalue',app.E98,'showbaseline','off','HandleVisibility', 'off');
                    plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'HandleVisibility', 'off');
            end
        end
        plot(app.UIAxes, app.t, app.BaU(app.t), 'r-', 'HandleVisibility', 'off');
        
        total = [];
        for i = index
            cost = str2double(app.wedgefile{i,8});
            total = [total,cost];
        end
        total = sum(total);
        app.TotalCostTextArea.Value = num2str(total);
        app.TotalWedgesTextArea.Value = num2str(m);
        sectorsplit = [];
        for i = index
            if size(strsplit(app.wedgefile{i,4},',')) == [1 2]
                sectorsplit = [sectorsplit;strsplit(app.wedgefile{i,4},',')];
            else
                sectorsplit = [sectorsplit; {app.wedgefile{i,4}, 'Null'}];
            end
        end
        for i = 1:numel(index)
            for s = 1:2
                if strcmp(app.TextArea.Value{:}, sectorsplit{i,s})
                        app.TextArea_7.Value = num2str(str2double(app.TextArea_7.Value{:,:}) + 1);
                end
                if strcmp(app.TextArea_2.Value{:,:}, sectorsplit{i,s})
                        app.TextArea_8.Value = num2str(str2double(app.TextArea_8.Value{:,:}) + 1);
                end
                if strcmp(app.TextArea_3.Value{:,:}, sectorsplit{i,s})
                    app.TextArea_9.Value = num2str(str2double(app.TextArea_9.Value{:,:}) + 1);
                end
            end
        end
     end

app.E1 = app.E1 + 1;
y = log([app.BaU(app.start_year), app.E1])';
A = [1, app.start_year; 1, (app.start_year+50)];
coefs = A\y;
cla(app.UIAxes2, 'reset');
hold(app.UIAxes2, 'on');
plot(app.UIAxes2, 1959:2068, app.ppm, 'k-');
legend(app.UIAxes2, 'Baseline', 'Location', 'northwest');
xlabel(app.UIAxes2, 'Time (yrs)');
ylabel(app.UIAxes2, 'Atm Carbon (ppm)');
l = app.start_year-1958;
ppm_new = zeros(1,numel(l:110));
ppm_new(1) = 2.1*app.ppm(l);
for i = 1:numel(l:109)
    ppm_new(i+1) = ppm_new(i) + 0.5*(coefs(1).*coefs(2).^(app.t_imp(i)));
end
ppm_new = ppm_new/2.1;
plot(app.UIAxes2, app.t_imp, ppm_new, 'r--', 'DisplayName', 'Current Strategy');
cla(app.UIAxes3, 'reset');
hold(app.UIAxes3, 'on');
plot(app.UIAxes3, 1959:2068, app.warming(app.lambda), 'k-');
legend(app.UIAxes3, 'Baseline', 'Location', 'northwest');
xlabel(app.UIAxes3, 'Time (yrs)');
ylabel(app.UIAxes3, 'Mean Warming (C)');
app.delta_F = 5.35*log(ppm_new./278);
warming_ = @(lambda) lambda*app.delta_F;
plot(app.UIAxes3, app.t_imp, warming_(app.lambda), 'r--', 'DisplayName', 'Current Strategy');
app.delta_F = 5.35*log(app.ppm./278);
    catch
        app.DialogueBoxTextArea.Value = 'Something went wrong. Please refer to the tutorial.';
    end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
         %this will allow the user to toggle between viewing the amount of wedges needed to achieve certain goals
         %the estimates for necessary wedges were mathematically computed using the slope of a wedge assuming wedges
         %resulted in 1GtC/yr loss over a 50yr span.
            selectedButton = app.ButtonGroup_2.SelectedObject;
            if isempty(app.t_imp)
                app.TextArea_10.Value = '0';
            elseif app.start_year == 2068
                app.TextArea_10.Value = '0';
                app.DialogueBoxTextArea.Value = 'No progress can be made by 2068 if beginning at 2068.';
            else
                if strcmp(selectedButton.Text, 'Flatline')
                    app.TextArea_10.Value = num2str(round(app.BaU(app.start_year)));
                elseif strcmp(selectedButton.Text, '1990 Levels')
                    app.TextArea_10.Value = num2str(round(50*(app.BaU(app.start_year)-app.E90)/(2068-app.start_year))+10);
                elseif strcmp(selectedButton.Text, '80% 1990 Levels')
                    app.TextArea_10.Value = num2str(round(50*(app.BaU(app.start_year)-app.E98)/(2068-app.start_year))+10);
                end
            end
        
        end

        % Button pushed function: RandomButton
        function RandomButtonPushed(app, event)
try
    if isempty(app.wedgefile)
        app.DialogueBoxTextArea.Value = 'Please upload the wedge file first';
        return
    else
[m,~] = size(app.wedgefile);
    end
r = randi(1000);
rng(r);
r = randi(m);
% this code is the select button applied to the random option;
app.SelectedWedgeDescriptionTextArea.Value = '';
if isempty(app.t_imp)
    app.DialogueBoxTextArea.Value = 'Please select an implementation year before selecting strategies';
else
currentWedge = app.UploadedWedgeChoicesDropDown.Items{r};
app.DialogueBoxTextArea.Value = '';
[m,~] = size(app.wedgefile);
app.wedgesbeingused = [app.wedgesbeingused; {currentWedge}];
[k,~] = size(app.wedgesbeingused);
j = 0;
% the char current wedge is stored in app.wedgesbeingused to be referenced by the export button

%this code will find additional info about the wedge in the original wedge file
%this code chunk will ensure we dont go over contraints
if k == 1
    [];
else
    for i = 1:k
        if strcmp(app.wedgesbeingused{i,:}, currentWedge)
            j = j+1;
            if rem(k,2) == 1
                if j >= k/2
                    app.wedgesbeingused(k,:) = [];
                    app.DialogueBoxTextArea.Value = 'Error: a single strategy cannot account for more than half of the wedges used.';
                    return
                else
                app.DialogueBoxTextArea.Value = '';
                end
            elseif rem(k,2) == 0
                if j > k/2
                    app.wedgesbeingused(k,:) = [];
                    app.DialogueBoxTextArea.Value = 'Error: a single strategy cannot account for more than half of the wedges used.';
                    return
                else
                app.DialogueBoxTextArea.Value = '';
                end
            end
        end
    end
end
%logistical info
app.TotalWedgesTextArea.Value = num2str(k);
%accounting for dynamically updating contraints counts
sectorsplit = [];
for i = 1:m
    if size(strsplit(app.wedgefile{i,4},',')) == [1 2]
        sectorsplit = [sectorsplit;strsplit(app.wedgefile{i,4},',')];
    else
        sectorsplit = [sectorsplit; {app.wedgefile{i,4}, 'Null'}];
    end
end
for i = 1:m
    for s = 1:2
        if strcmp(currentWedge, app.wedgefile{i,3})
            if strcmp(app.TextArea.Value{:}, sectorsplit{i,s})
                if strcmp(app.TextArea_6.Value{:,:},app.TextArea_7.Value{:,:})
                    app.DialogueBoxTextArea.Value = 'Please select a strategy from a different sector';
                    return
                else
                    app.TextArea_7.Value = num2str(str2double(app.TextArea_7.Value{:,:}) + 1);
                end
            elseif strcmp(app.TextArea_2.Value{:}, sectorsplit{i,s})
                if strcmp(app.TextArea_5.Value{:,:},app.TextArea_8.Value{:,:})
                    app.DialogueBoxTextArea.Value = 'Please select a strategy from a different sector';
                    return
                else
                    app.TextArea_8.Value = num2str(str2double(app.TextArea_8.Value{:,:}) + 1);
                end
            elseif strcmp(app.TextArea_3.Value{:}, sectorsplit{i,s})
                if strcmp(app.TextArea_4.Value{:,:},app.TextArea_9.Value{:,:})
                    app.DialogueBoxTextArea.Value = 'Please select a strategy from a different sector';
                    return
                else
                    app.TextArea_9.Value = num2str(str2double(app.TextArea_9.Value{:,:}) + 1);
                end
            end
        end
    end
end
%logistics - cost
app.E1 = app.E1 - 1;     
for i = 1:m
    if strcmp(currentWedge, app.wedgefile{i,3})
        app.TotalCostTextArea.Value = num2str(str2double(app.TotalCostTextArea.Value{:,:})...
            + str2double(app.wedgefile{i,8}));
    end
end


[J,~] = size(app.wedgefile);
    for j = 1:J
        if strcmp(currentWedge, app.wedgefile{j,3})
           index = [j];
        end
    end
%the fun stuff - color coded graphing and exponential interpolation to properly fit each wedge.
app.SelectedWedgeDescriptionTextArea.Value = app.wedgefile{index,5};
y = log([app.BaU(app.start_year), app.E1])';
y_hat = log([app.BaU(app.start_year), (app.E1+1)])';
A = [1, app.start_year; 1, (app.start_year+50)];
coefs = A\y;
coefs2 = A\y_hat;
coefs = exp(coefs);
coefs2 = exp(coefs2);
if strcmp(app.wedgefile{index,2},'Efficiency and Conservation')
        area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','y','basevalue',app.E98,'showbaseline','off', 'Edgecolor','k', 'DisplayName', currentWedge);
        area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','c','Edgecolor','k','basevalue',app.E98,'showbaseline','off','HandleVisibility', 'off');
        plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'HandleVisibility', 'off');
elseif strcmp(app.wedgefile{index,2}, 'Fossil Fuel')
        area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','b','basevalue',app.E98,'showbaseline','off', 'Edgecolor','k', 'DisplayName', currentWedge);
        area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','c','Edgecolor','k','basevalue',app.E98,'showbaseline','off','HandleVisibility', 'off');
        plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'HandleVisibility', 'off');
elseif strcmp(app.wedgefile{index,2}, 'Renewables and Biostorage')
        area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','g','basevalue',app.E98,'showbaseline','off', 'Edgecolor','k', 'DisplayName', currentWedge);
        area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','c','Edgecolor','k','basevalue',app.E98,'showbaseline','off','HandleVisibility', 'off');
        plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'HandleVisibility', 'off');
elseif strcmp(app.wedgefile{index,2}, 'Nuclear')
        area(app.UIAxes,app.t_imp,coefs2(1)*coefs2(2).^(app.t_imp),'facecolor','r','basevalue',app.E98,'showbaseline','off', 'Edgecolor','k', 'DisplayName', currentWedge);
        area(app.UIAxes,app.t_imp,coefs(1).*coefs(2).^(app.t_imp),'facecolor','c','Edgecolor','k','basevalue',app.E98,'showbaseline','off','HandleVisibility', 'off');
        plot(app.UIAxes, app.t_imp, app.flatline_E, 'k--', 'HandleVisibility', 'off');
end
plot(app.UIAxes, app.t, app.BaU(app.t), 'r-', 'HandleVisibility', 'off');
    
cla(app.UIAxes2, 'reset');
hold(app.UIAxes2, 'on');
plot(app.UIAxes2, 1959:2068, app.ppm, 'k-');
legend(app.UIAxes2, 'Baseline', 'Location', 'northwest');
xlabel(app.UIAxes2, 'Time (yrs)');
ylabel(app.UIAxes2, 'Atm Carbon (ppm)');
l = app.start_year-1958;
ppm_new = zeros(1,numel(l:110));
ppm_new(1) = 2.1*app.ppm(l);
for i = 1:numel(l:109)
    ppm_new(i+1) = ppm_new(i) + 0.5*(coefs(1).*coefs(2).^(app.t_imp(i)));
end
ppm_new = ppm_new/2.1;
plot(app.UIAxes2, app.t_imp, ppm_new, 'r--', 'DisplayName', 'Current Strategy');
cla(app.UIAxes3, 'reset');
hold(app.UIAxes3, 'on');
plot(app.UIAxes3, 1959:2068, app.warming(app.lambda), 'k-');
legend(app.UIAxes3, 'Baseline', 'Location', 'northwest');
xlabel(app.UIAxes3, 'Time (yrs)');
ylabel(app.UIAxes3, 'Mean Warming (C)');
app.delta_F = 5.35*log(ppm_new./278);
warming_ = @(lambda) lambda*app.delta_F;
plot(app.UIAxes3, app.t_imp, warming_(app.lambda), 'r--', 'DisplayName', 'Current Strategy');
app.delta_F = 5.35*log(app.ppm./278);
end
catch
    app.DialogueBoxTextArea.Value = 'Something went wrong. Please ensure you are properly following tutorial directions.';
end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main Tab';
            app.UIAxes = uiaxes(app.MainTab);
            xlabel(app.UIAxes, 'Time (Years)')
            ylabel(app.UIAxes, 'Carbon Emissions (GtC/yr)')
            app.DirectoryTextAreaLabel = uilabel(app.MainTab);
            app.DirectoryTextAreaLabel.Text = 'Directory';
            app.DirectoryTextArea = uitextarea(app.MainTab);
            app.ExportButton = uibutton(app.MainTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ImplementationYearDropDownLabel = uilabel(app.MainTab);
            app.ImplementationYearDropDownLabel.Text = 'Implementation Year';
            app.ImplementationYearDropDown = uidropdown(app.MainTab);
            app.ImplementationYearDropDown.Items = {'2018', '2019', '2020', '2021', '2022', '2023', '2024', '2025', '2026', '2027', '2028', '2029', '2030', '2031', '2032', '2033', '2034', '2035', '2036', '2037', '2038', '2039', '2040', '2041', '2042', '2043', '2044', '2045', '2046', '2047', '2048', '2049', '2050', '2051', '2052', '2053', '2054', '2055', '2056', '2057', '2058', '2059', '2060', '2061', '2062', '2063', '2064', '2065', '2066', '2067', '2068'};
            app.ImplementationYearDropDown.Value = '2018';
            app.SelectButton_2 = uibutton(app.MainTab, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed2, true);
            app.SelectButton_2.Text = 'Select';
            app.TotalCostTextAreaLabel = uilabel(app.MainTab);
            app.TotalCostTextAreaLabel.Text = 'Total Cost';
            app.TotalCostTextArea = uitextarea(app.MainTab);
            app.TotalCostTextArea.Editable = 'off';
            app.TotalWedgesTextAreaLabel = uilabel(app.MainTab);
            app.TotalWedgesTextAreaLabel.Text = 'Total Wedges';
            app.TotalWedgesTextArea = uitextarea(app.MainTab);
            app.TotalWedgesTextArea.Editable = 'off';
            app.TextArea_10 = uitextarea(app.MainTab);
            app.TextArea_10.Editable = 'off';
            app.StrategiesFileLabel = uilabel(app.MainTab);
            app.StrategiesFileLabel.Text = 'Strategies File';
            app.WedgesFileLabel = uilabel(app.MainTab);
            app.WedgesFileLabel.Text = 'Wedges File';
            app.ConstraintsFileLabel = uilabel(app.MainTab);
            app.ConstraintsFileLabel.Text = 'Constraints File';
            app.UploadButton_2 = uibutton(app.MainTab, 'push');
            app.UploadButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadButton_2Pushed, true);
            app.UploadButton_2.Text = 'Upload';
            app.UploadButton_3 = uibutton(app.MainTab, 'push');
            app.UploadButton_3.ButtonPushedFcn = createCallbackFcn(app, @UploadButton_3Pushed, true);
            app.UploadButton_3.Text = 'Upload';
            app.UploadButton_4 = uibutton(app.MainTab, 'push');
            app.UploadButton_4.ButtonPushedFcn = createCallbackFcn(app, @UploadButton_4Pushed, true);
            app.UploadButton_4.Text = 'Upload';
            app.ResetButton = uibutton(app.MainTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UploadedWedgeChoicesDropDownLabel = uilabel(app.MainTab);
            app.UploadedWedgeChoicesDropDownLabel.Text = 'Uploaded Wedge Choices';
            app.UploadedWedgeChoicesDropDown = uidropdown(app.MainTab);
            app.UploadedWedgeChoicesDropDown.Items = {};
            app.UploadedWedgeChoicesDropDown.Value = {};
            app.SelectButton = uibutton(app.MainTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed2, true);
            app.SelectButton.Text = 'Select';
            app.SectorContraintsLabel = uilabel(app.MainTab);
            app.SectorContraintsLabel.Text = 'Sector Contraints';
            app.CountersLabel = uilabel(app.MainTab);
            app.CountersLabel.Text = 'Counters';
            app.TextArea_7 = uitextarea(app.MainTab);
            app.TextArea_7.Editable = 'off';
            app.TextArea = uitextarea(app.MainTab);
            app.TextArea_6 = uitextarea(app.MainTab);
            app.TextArea_6.Editable = 'off';
            app.TextArea_2 = uitextarea(app.MainTab);
            app.TextArea_5 = uitextarea(app.MainTab);
            app.TextArea_5.Editable = 'off';
            app.TextArea_8 = uitextarea(app.MainTab);
            app.TextArea_8.Editable = 'off';
            app.TextArea_3 = uitextarea(app.MainTab);
            app.TextArea_4 = uitextarea(app.MainTab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_9 = uitextarea(app.MainTab);
            app.TextArea_9.Editable = 'off';
            app.DialogueBoxTextAreaLabel = uilabel(app.MainTab);
            app.DialogueBoxTextAreaLabel.Text = 'Dialogue Box';
            app.DialogueBoxTextArea = uitextarea(app.MainTab);
            app.DialogueBoxTextArea.Editable = 'off';
            app.RemoveButton = uibutton(app.MainTab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.SelectedWedgeDescriptionLabel = uilabel(app.MainTab);
            app.SelectedWedgeDescriptionLabel.Text = 'Selected Wedge Description';
            app.SelectedWedgeDescriptionTextArea = uitextarea(app.MainTab);
            app.ButtonGroup_2 = uibuttongroup(app.MainTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.FlatlineButton = uitogglebutton(app.ButtonGroup_2);
            app.FlatlineButton.Text = 'Flatline';
            app.FlatlineButton.Value = true;
            app.LevelsButton = uitogglebutton(app.ButtonGroup_2);
            app.LevelsButton.Text = '1990 Levels';
            app.LevelsButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.LevelsButton_2.Text = '80% 1990 Levels';
            app.WedgesNeededToLabel = uilabel(app.MainTab);
            app.WedgesNeededToLabel.Text = 'Wedges Needed To';
            app.Button = uibutton(app.MainTab, 'push');
            app.Button.Text = '';
            app.Button_5 = uibutton(app.MainTab, 'push');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.MainTab, 'push');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.MainTab, 'push');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.MainTab, 'push');
            app.Button_8.Text = '';
            app.NuclearLabel = uilabel(app.MainTab);
            app.NuclearLabel.Text = 'Nuclear';
            app.FuelLabel = uilabel(app.MainTab);
            app.FuelLabel.Text = 'Fuel';
            app.ConservationLabel = uilabel(app.MainTab);
            app.ConservationLabel.Text = 'Conservation';
            app.BiostorageLabel = uilabel(app.MainTab);
            app.BiostorageLabel.Text = 'Biostorage';
            app.UnmitigatedEmissionsLabel = uilabel(app.MainTab);
            app.UnmitigatedEmissionsLabel.Text = 'Unmitigated Emissions';
            app.KeyLabel = uilabel(app.MainTab);
            app.KeyLabel.Text = 'Key';
            app.RandomButton = uibutton(app.MainTab, 'push');
            app.RandomButton.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.RandomButton.Text = 'Random';
            app.AtmosphericCarbonppmvsTimeTab = uitab(app.TabGroup);
            app.AtmosphericCarbonppmvsTimeTab.Title = 'Atmospheric Carbon (ppm) vs Time';
            app.UIAxes2 = uiaxes(app.AtmosphericCarbonppmvsTimeTab);
            xlabel(app.UIAxes2, 'Time (yrs)')
            ylabel(app.UIAxes2, 'Atm. Carbon (ppm)')
            app.MeanWarmingDegreesCelsiusvsTimeTab = uitab(app.TabGroup);
            app.MeanWarmingDegreesCelsiusvsTimeTab.Title = 'Mean Warming (Degrees Celsius) vs Time';
            app.UIAxes3 = uiaxes(app.MeanWarmingDegreesCelsiusvsTimeTab);
            xlabel(app.UIAxes3, 'Time (yrs)')
            ylabel(app.UIAxes3, 'Mean Warming (C)')
            app.EnterUserValueForLambdaandPressSelectTextAreaLabel = uilabel(app.MeanWarmingDegreesCelsiusvsTimeTab);
            app.EnterUserValueForLambdaandPressSelectTextAreaLabel.Text = 'Enter User Value For Lambda and Press Select';
            app.EnterUserValueForLambdaandPressSelectTextArea = uitextarea(app.MeanWarmingDegreesCelsiusvsTimeTab);
            app.SelectButton_3 = uibutton(app.MeanWarmingDegreesCelsiusvsTimeTab, 'push');
            app.SelectButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_3Pushed2, true);
            app.SelectButton_3.Text = 'Select';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end